classdef PC_UP_Control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel_2                        matlab.ui.container.Panel
        SimulationProgress             simulink.ui.control.SimulationProgress
        UITable                        matlab.ui.control.Table
        TCP_DisConnect_Button_8        matlab.ui.control.Button
        TCP_DisConnect_Button_7        matlab.ui.control.Button
        TCP_DisConnect_Button_6        matlab.ui.control.Button
        TCP_Port_EditField_2           matlab.ui.control.EditField
        Label_6                        matlab.ui.control.Label
        TCP_Address_EditField_20       matlab.ui.control.EditField
        IPEditFieldLabel_2             matlab.ui.control.Label
        TCP_DisConnect_Button_5        matlab.ui.control.Button
        TCP_Connect_Button_2           matlab.ui.control.Button
        FMCW_Stop_Button               matlab.ui.control.Button
        FMCW_Start_Button              matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        fpsLabel                       matlab.ui.control.Label
        Stereo_Camera_Frame_Rate_EditField  matlab.ui.control.EditField
        ADCLabel_16                    matlab.ui.control.Label
        MIMO_Control_Mode_DropDown     matlab.ui.control.DropDown
        MIMODropDownLabel              matlab.ui.control.Label
        MIMO_Interference_Mode_DropDown  matlab.ui.control.DropDown
        MIMODropDown_2Label            matlab.ui.control.Label
        MIMO_Rx_Pola_Mode_DropDown     matlab.ui.control.DropDown
        MIMO_Tx_Pola_Mode_DropDown     matlab.ui.control.DropDown
        Label_4                        matlab.ui.control.Label
        DropDownLabel                  matlab.ui.control.Label
        sLabel                         matlab.ui.control.Label
        MIMO_Work_Time_EditField       matlab.ui.control.EditField
        ADCLabel_18                    matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        MIMO_Frame_Num_EditField       matlab.ui.control.EditField
        ADCLabel_14                    matlab.ui.control.Label
        nsLabel                        matlab.ui.control.Label
        MIMO_Signal_Delay_EditField    matlab.ui.control.EditField
        ADCLabel_15                    matlab.ui.control.Label
        usLabel_3                      matlab.ui.control.Label
        FMCW_Pulse_Width_EditField     matlab.ui.control.EditField
        ADCLabel_17                    matlab.ui.control.Label
        dBmLabel                       matlab.ui.control.Label
        usLabel_2                      matlab.ui.control.Label
        usLabel                        matlab.ui.control.Label
        MHzusLabel                     matlab.ui.control.Label
        MHzLabel_4                     matlab.ui.control.Label
        GHzLabel                       matlab.ui.control.Label
        FMCW_Output_Power_EditField    matlab.ui.control.EditField
        FMCW_Frame_Period_EditField    matlab.ui.control.EditField
        FMCW_Pulse_Period_EditField    matlab.ui.control.EditField
        FMCW_Slope_EditField           matlab.ui.control.EditField
        FMCW_Band_Width_EditField      matlab.ui.control.EditField
        ADCLabel_12                    matlab.ui.control.Label
        ADCLabel_11                    matlab.ui.control.Label
        ADCLabel_10                    matlab.ui.control.Label
        ADCLabel_9                     matlab.ui.control.Label
        ADCLabel_8                     matlab.ui.control.Label
        FMCW_Center_Freq_EditField     matlab.ui.control.EditField
        ADCLabel_7                     matlab.ui.control.Label
        ADCPanel                       matlab.ui.container.Panel
        ADC_Sampling_Rate_Label        matlab.ui.control.Label
        ADC_Sampling_FIFO_Output_Delay_Label  matlab.ui.control.Label
        ADC_Sampling_Total_Time_Label  matlab.ui.control.Label
        MHzLabel_5                     matlab.ui.control.Label
        ADC_Sampling_Trigger_Delay_Label  matlab.ui.control.Label
        ADC_Sampling_FIFO_Output_Delay_EditField  matlab.ui.control.EditField
        ADC_Sampling_Point_Num_EditField  matlab.ui.control.EditField
        ADC_Sampling_Start_Byte_EditField  matlab.ui.control.EditField
        ADC_Sampling_Start_Delay_Fine_Tuning_EditField  matlab.ui.control.EditField
        ADC_Sampling_Trigger_Delay_EditField  matlab.ui.control.EditField
        ADC_Sampling_Rate_Div_Multiples_EditField  matlab.ui.control.EditField
        ADCLabel_6                     matlab.ui.control.Label
        ADCLabel_5                     matlab.ui.control.Label
        ADCLabel_4                     matlab.ui.control.Label
        ADCLabel_3                     matlab.ui.control.Label
        ADCLabel_2                     matlab.ui.control.Label
        ADCLabel                       matlab.ui.control.Label
        Message_Display_TextArea       matlab.ui.control.TextArea
        Label_2                        matlab.ui.control.Label
        TCP_Port_ADC_Sampling_Board_1_EditField  matlab.ui.control.EditField
        Label                          matlab.ui.control.Label
        TCP_Address_ADC_Sampling_Board_1_EditField  matlab.ui.control.EditField
        IPEditFieldLabel               matlab.ui.control.Label
        TCP_DisConnect_ADC_Sampling_Board_1_Button  matlab.ui.control.Button
        TCP_Connect_ADC_Sampling_Board_1_Button  matlab.ui.control.Button
        Parameter_Config_Button        matlab.ui.control.Button
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        TCP_Client_Obj_ADC_Sampling_Board_1                     % TCP Client Object
        TCP_Timer_ADC_Sampling_Board_1                          % TCP Timer
    end
    
    methods (Access = private)
        function TCP_ADC_Sampling_Board_1_Read_Callback(app)
            if (app.TCP_Client_Obj_ADC_Sampling_Board_1.NumBytesAvailable > 0)
                TCP_Receive_Data = read(app.TCP_Client_Obj_ADC_Sampling_Board_1, app.TCP_Client_Obj_ADC_Sampling_Board_1.NumBytesAvailable, 'uint8');
                TCP_Echo_Message_Head_ID = uint8([0x4D, 0x53, 0x41, 0x47]);
                Head_ID_Index = strfind(TCP_Receive_Data, TCP_Echo_Message_Head_ID);
                if (~isempty(Head_ID_Index))
                    if (Head_ID_Index(1) ~= 1)
                        TCP_Echo_Message_Bytes = TCP_Receive_Data(1:(Head_ID_Index(1)-1));
                        TCP_Echo_Message = native2unicode(TCP_Echo_Message_Bytes, 'UTF-8');

                        Original_Text = string(app.Message_Display_TextArea.Value);
                        app.Message_Display_TextArea.Value = [Original_Text; TCP_Echo_Message];
                    end
                    if (length(Head_ID_Index) > 1)
                        for i = 1:length(Head_ID_Index)-1
                            TCP_Echo_Message_Index = Head_ID_Index(i) + 4;
                            if TCP_Echo_Message_Index <= length(TCP_Receive_Data)
                                TCP_Echo_Message_Bytes = TCP_Receive_Data(TCP_Echo_Message_Index:(Head_ID_Index(i+1)-1));
                                TCP_Echo_Message = native2unicode(TCP_Echo_Message_Bytes, 'UTF-8');
                                Original_Text = string(app.Message_Display_TextArea.Value);
                                app.Message_Display_TextArea.Value = [Original_Text; "回执: "; TCP_Echo_Message];
                            end
                        end
                        TCP_Echo_Message_Index = Head_ID_Index(i+1) + 4;
                        if TCP_Echo_Message_Index <= length(TCP_Receive_Data)
                            TCP_Echo_Message_Bytes = TCP_Receive_Data(TCP_Echo_Message_Index:end);
                            TCP_Echo_Message = native2unicode(TCP_Echo_Message_Bytes, 'UTF-8');
                            Original_Text = string(app.Message_Display_TextArea.Value);
                            app.Message_Display_TextArea.Value = [Original_Text; "回执: "; TCP_Echo_Message];
                        end
                    elseif (isscalar(Head_ID_Index))
                        TCP_Echo_Message_Index = Head_ID_Index(1) + 4;
                        if TCP_Echo_Message_Index <= length(TCP_Receive_Data)
                            TCP_Echo_Message_Bytes = TCP_Receive_Data(TCP_Echo_Message_Index:end);
                            TCP_Echo_Message = native2unicode(TCP_Echo_Message_Bytes, 'UTF-8');
                            Original_Text = string(app.Message_Display_TextArea.Value);
                            app.Message_Display_TextArea.Value = [Original_Text; "回执: "; TCP_Echo_Message];
                        end
                    end
                else
                    TCP_Echo_Message_Bytes = TCP_Receive_Data;
                    TCP_Echo_Message = native2unicode(TCP_Echo_Message_Bytes, 'UTF-8');
                    Original_Text = string(app.Message_Display_TextArea.Value);
                    app.Message_Display_TextArea.Value = [Original_Text; TCP_Echo_Message];
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % TODO
        end

        % Button pushed function: TCP_Connect_ADC_Sampling_Board_1_Button
        function TCP_Connect_ADC_Sampling_Board_1_ButtonPushed(app, event)
            try
                app.TCP_Client_Obj_ADC_Sampling_Board_1 = tcpclient(app.TCP_Address_ADC_Sampling_Board_1_EditField.Value, str2double(app.TCP_Port_ADC_Sampling_Board_1_EditField.Value));
                app.TCP_Timer_ADC_Sampling_Board_1 = timer('ExecutionMode', 'fixedRate', 'Period', 0.1, 'TimerFcn', @(~,~)app.TCP_ADC_Sampling_Board_1_Read_Callback());
                start(app.TCP_Timer_ADC_Sampling_Board_1);
                Message = "TCP连接成功: " + app.TCP_Address_ADC_Sampling_Board_1_EditField.Value + ":" + app.TCP_Port_ADC_Sampling_Board_1_EditField.Value;
            catch ME
                Message = "TCP连接失败:" + ME.message;
            end
            Original_Text = string(app.Message_Display_TextArea.Value);
            app.Message_Display_TextArea.Value = [Original_Text; Message];
        end

        % Button pushed function: 
        % TCP_DisConnect_ADC_Sampling_Board_1_Button
        function TCP_DisConnect_ADC_Sampling_Board_1_ButtonPushed(app, event)
            try
                if isprop(app, 'TCP_Client_Obj_ADC_Sampling_Board_1') && ~isempty(app.TCP_Client_Obj_ADC_Sampling_Board_1)
                    delete(app.TCP_Client_Obj_ADC_Sampling_Board_1);
                    app.TCP_Client_Obj_ADC_Sampling_Board_1 = [];
                    Message = "TCP连接已断开";
                else
                    Message = "无有效TCP连接可断开";
                end
            catch ME
                Message = "断开连接时出错: " + ME.message;
            end
            Original_Text = string(app.Message_Display_TextArea.Value);
            app.Message_Display_TextArea.Value = [Original_Text; Message];
            try
                if isvalid(app.TCP_Timer_ADC_Sampling_Board_1)
                    stop(app.TCP_Timer_ADC_Sampling_Board_1);
                    delete(app.TCP_Timer_ADC_Sampling_Board_1);
                    Message = "TCP计时器已停止";
                else
                    Message = "无有效TCP计时器可停止";
                end
            catch ME
                Message = "停止计时器失败: " + ME.message;
            end
            Original_Text = string(app.Message_Display_TextArea.Value);
            app.Message_Display_TextArea.Value = [Original_Text; Message];
        end

        % Value changed function: ADC_Sampling_Rate_Div_Multiples_EditField
        function ADC_Sampling_Rate_Div_Multiples_EditFieldValueChanged(app, event)
            value = app.ADC_Sampling_Rate_Div_Multiples_EditField.Value;
            if ((str2double(value) < 5) || (mod(str2double(value), 1) ~= 0))
                uialert(app.UIFigure, 'ADC采样率分频系数是>=5的正整数', '错误');
            else
                ADC_Sampling_Rate = 2000 / str2double(value);
                app.ADC_Sampling_Rate_Label.Text = "（采样率：" + ADC_Sampling_Rate + " Msps）";
                ADC_Sampling_Point_Num = app.ADC_Sampling_Point_Num_EditField.Value;
                ADC_Sampling_Total_Time = str2double(ADC_Sampling_Point_Num) / ADC_Sampling_Rate;
                app.ADC_Sampling_Total_Time_Label.Text = "个（总采样时间：" + ADC_Sampling_Total_Time + " us）";
            end
        end

        % Value changed function: ADC_Sampling_Trigger_Delay_EditField
        function ADC_Sampling_Trigger_Delay_EditFieldValueChanged(app, event)
            value = app.ADC_Sampling_Trigger_Delay_EditField.Value;
            if ((str2double(value) < 0) || (mod(str2double(value), 1) ~= 0))
                uialert(app.UIFigure, 'ADC开始采样触发时延是正整数', '错误');
            else
                ADC_Sampling_Trigger_Delay_Time = str2double(value) / 400;
                app.ADC_Sampling_Trigger_Delay_Label.Text = "个400 MHz时钟（" + ADC_Sampling_Trigger_Delay_Time + " us）";
            end
        end

        % Value changed function: ADC_Sampling_Point_Num_EditField
        function ADC_Sampling_Point_Num_EditFieldValueChanged(app, event)
            value = app.ADC_Sampling_Point_Num_EditField.Value;
            if ((str2double(value) < 0) || (mod(str2double(value), 1) ~= 0))
                uialert(app.UIFigure, 'ADC采样点数是正整数', '错误');
            else
                ADC_Sampling_Rate_Div_Multiples = app.ADC_Sampling_Rate_Div_Multiples_EditField.Value;
                ADC_Sampling_Rate = 2000 / str2double(ADC_Sampling_Rate_Div_Multiples);
                ADC_Sampling_Total_Time = str2double(value) / ADC_Sampling_Rate;
                app.ADC_Sampling_Total_Time_Label.Text = "个（总采样时间：" + ADC_Sampling_Total_Time + " us）";
            end
        end

        % Value changed function: ADC_Sampling_FIFO_Output_Delay_EditField
        function ADC_Sampling_FIFO_Output_Delay_EditFieldValueChanged(app, event)
            value = app.ADC_Sampling_FIFO_Output_Delay_EditField.Value;
            if ((str2double(value) < 0) || (mod(str2double(value), 1) ~= 0))
                uialert(app.UIFigure, 'FIFO开始输出触发时延是正整数', '错误');
            else
                ADC_Sampling_FIFO_Output_Delay_Time = str2double(value) / 390.625;
                app.ADC_Sampling_FIFO_Output_Delay_Label.Text = "个390.625 MHz时钟（>" + floor(ADC_Sampling_FIFO_Output_Delay_Time) + " us）";
            end
        end

        % Value changed function: FMCW_Band_Width_EditField
        function FMCW_Band_Width_EditFieldValueChanged(app, event)
            value = app.FMCW_Band_Width_EditField.Value;
            FMCW_Slope = app.FMCW_Slope_EditField.Value;
            app.FMCW_Pulse_Width_EditField.Value = num2str(str2double(value) / str2double(FMCW_Slope));
            FMCW_Pulse_Period = app.FMCW_Pulse_Period_EditField.Value;
            if (str2double(FMCW_Pulse_Period) < str2double(app.FMCW_Pulse_Width_EditField.Value))
                uialert(app.UIFigure, 'FMCW脉冲周期>FMCW脉冲宽度', '错误');
            end
        end

        % Value changed function: FMCW_Slope_EditField
        function FMCW_Slope_EditFieldValueChanged(app, event)
            value = app.FMCW_Slope_EditField.Value;
            FMCW_Band_Width = app.FMCW_Band_Width_EditField.Value;
            app.FMCW_Pulse_Width_EditField.Value = num2str(str2double(FMCW_Band_Width) / str2double(value));
            FMCW_Pulse_Period = app.FMCW_Pulse_Period_EditField.Value;
            if (str2double(FMCW_Pulse_Period) < str2double(app.FMCW_Pulse_Width_EditField.Value))
                uialert(app.UIFigure, 'FMCW脉冲周期>FMCW脉冲宽度', '错误');
            end
        end

        % Value changed function: FMCW_Frame_Period_EditField
        function FMCW_Frame_Period_EditFieldValueChanged(app, event)
            value = app.FMCW_Frame_Period_EditField.Value;
            if (app.MIMO_Interference_Mode_DropDown.Value == "上/下阵列")
                Channel_Num = 16;
            else
                Channel_Num = 8;
            end
            if (app.MIMO_Tx_Pola_Mode_DropDown.Value == "H/V极化")
                if (app.MIMO_Rx_Pola_Mode_DropDown.Value == "H/V极化")
                    MIMO_Pola_Num = 4;
                else
                    MIMO_Pola_Num = 2;
                end
            else
                if (app.MIMO_Rx_Pola_Mode_DropDown.Value == "H/V极化")
                    MIMO_Pola_Num = 2;
                else
                    MIMO_Pola_Num = 1;
                end
            end
            if (str2double(value) < MIMO_Pola_Num * Channel_Num * str2double(app.FMCW_Pulse_Period_EditField.Value))
                uialert(app.UIFigure, 'FMCW帧周期>通道数*极化数*FMCW脉冲周期', '错误');
            else
                MIMO_Frame_Num = app.MIMO_Frame_Num_EditField.Value;
                app.MIMO_Work_Time_EditField.Value = num2str(str2double(value) * str2double(MIMO_Frame_Num) / 1000000);
            end
        end

        % Value changed function: MIMO_Frame_Num_EditField
        function MIMO_Frame_Num_EditFieldValueChanged(app, event)
            value = app.MIMO_Frame_Num_EditField.Value;
            FMCW_Frame_Period = app.FMCW_Frame_Period_EditField.Value;
            app.MIMO_Work_Time_EditField.Value = num2str(str2double(value) * str2double(FMCW_Frame_Period) / 1000000);
        end

        % Value changed function: FMCW_Pulse_Period_EditField
        function FMCW_Pulse_Period_EditFieldValueChanged(app, event)
            value = app.FMCW_Pulse_Period_EditField.Value;
            if (app.MIMO_Interference_Mode_DropDown.Value == "上/下阵列")
                Channel_Num = 16;
            else
                Channel_Num = 8;
            end
            if (app.MIMO_Tx_Pola_Mode_DropDown.Value == "H/V极化")
                if (app.MIMO_Rx_Pola_Mode_DropDown.Value == "H/V极化")
                    MIMO_Pola_Num = 4;
                else
                    MIMO_Pola_Num = 2;
                end
            else
                if (app.MIMO_Rx_Pola_Mode_DropDown.Value == "H/V极化")
                    MIMO_Pola_Num = 2;
                else
                    MIMO_Pola_Num = 1;
                end
            end
            if (str2double(value) < str2double(app.FMCW_Pulse_Width_EditField.Value))
                uialert(app.UIFigure, 'FMCW脉冲周期>FMCW脉冲宽度', '错误');
            elseif ((str2double(value) * Channel_Num * MIMO_Pola_Num) > str2double(app.FMCW_Frame_Period_EditField.Value))
                uialert(app.UIFigure, 'FMCW脉冲周期<FMCW帧周期/通道数/极化数', '错误');
            end
        end

        % Value changed function: MIMO_Tx_Pola_Mode_DropDown
        function MIMO_Tx_Pola_Mode_DropDownValueChanged(app, event)
            if (app.MIMO_Interference_Mode_DropDown.Value == "上/下阵列")
                Channel_Num = 16;
            else
                Channel_Num = 8;
            end
            if (app.MIMO_Tx_Pola_Mode_DropDown.Value == "H/V极化")
                if (app.MIMO_Rx_Pola_Mode_DropDown.Value == "H/V极化")
                    MIMO_Pola_Num = 4;
                else
                    MIMO_Pola_Num = 2;
                end
            else
                if (app.MIMO_Rx_Pola_Mode_DropDown.Value == "H/V极化")
                    MIMO_Pola_Num = 2;
                else
                    MIMO_Pola_Num = 1;
                end
            end
            if ((str2double(app.FMCW_Pulse_Period_EditField.Value) * Channel_Num * MIMO_Pola_Num) > str2double(app.FMCW_Frame_Period_EditField.Value))
                uialert(app.UIFigure, 'FMCW帧周期>通道数*极化数*FMCW脉冲周期', '错误');
            end
        end

        % Value changed function: MIMO_Rx_Pola_Mode_DropDown
        function MIMO_Rx_Pola_Mode_DropDownValueChanged(app, event)
            if (app.MIMO_Interference_Mode_DropDown.Value == "上/下阵列")
                Channel_Num = 16;
            else
                Channel_Num = 8;
            end
            if (app.MIMO_Tx_Pola_Mode_DropDown.Value == "H/V极化")
                if (app.MIMO_Rx_Pola_Mode_DropDown.Value == "H/V极化")
                    MIMO_Pola_Num = 4;
                else
                    MIMO_Pola_Num = 2;
                end
            else
                if (app.MIMO_Rx_Pola_Mode_DropDown.Value == "H/V极化")
                    MIMO_Pola_Num = 2;
                else
                    MIMO_Pola_Num = 1;
                end
            end
            if ((str2double(app.FMCW_Pulse_Period_EditField.Value) * Channel_Num * MIMO_Pola_Num) > str2double(app.FMCW_Frame_Period_EditField.Value))
                uialert(app.UIFigure, 'FMCW帧周期>通道数*极化数*FMCW脉冲周期', '错误');
            end
        end

        % Value changed function: MIMO_Interference_Mode_DropDown
        function MIMO_Interference_Mode_DropDownValueChanged(app, event)
            if (app.MIMO_Interference_Mode_DropDown.Value == "上/下阵列")
                Channel_Num = 16;
            else
                Channel_Num = 8;
            end
            if (app.MIMO_Tx_Pola_Mode_DropDown.Value == "H/V极化")
                if (app.MIMO_Rx_Pola_Mode_DropDown.Value == "H/V极化")
                    MIMO_Pola_Num = 4;
                else
                    MIMO_Pola_Num = 2;
                end
            else
                if (app.MIMO_Rx_Pola_Mode_DropDown.Value == "H/V极化")
                    MIMO_Pola_Num = 2;
                else
                    MIMO_Pola_Num = 1;
                end
            end
            if ((str2double(app.FMCW_Pulse_Period_EditField.Value) * Channel_Num * MIMO_Pola_Num) > str2double(app.FMCW_Frame_Period_EditField.Value))
                uialert(app.UIFigure, 'FMCW帧周期>通道数*极化数*FMCW脉冲周期', '错误');
            end
        end

        % Button pushed function: Parameter_Config_Button
        function Parameter_Config_ButtonPushed(app, event)
            ADC_Sampling_Rate_Div_Multiples = str2double(app.ADC_Sampling_Rate_Div_Multiples_EditField.Value);
            ADC_Sampling_Trigger_Delay = str2double(app.ADC_Sampling_Trigger_Delay_EditField.Value);
            ADC_Sampling_Start_Delay_Fine_Tuning = str2double(app.ADC_Sampling_Start_Delay_Fine_Tuning_EditField.Value);
            ADC_Sampling_Start_Byte = str2double(app.ADC_Sampling_Start_Byte_EditField.Value);
            ADC_Sampling_Point_Num = str2double(app.ADC_Sampling_Point_Num_EditField.Value);
            ADC_Sampling_FIFO_Output_Delay = str2double(app.ADC_Sampling_FIFO_Output_Delay_EditField.Value);
            Middle_Data_Check = 0x00001A2B;
            FMCW_Center_Freq = str2double(app.FMCW_Center_Freq_EditField.Value)*1000;
            FMCW_Band_Width = str2double(app.FMCW_Band_Width_EditField.Value);
            FMCW_Slope = str2double(app.FMCW_Slope_EditField.Value);
            FMCW_Pulse_Period = str2double(app.FMCW_Pulse_Period_EditField.Value);
            FMCW_Frame_Period = str2double(app.FMCW_Frame_Period_EditField.Value);
            FMCW_Output_Power = str2double(app.FMCW_Output_Power_EditField.Value);
            MIMO_Signal_Delay = str2double(app.MIMO_Signal_Delay_EditField.Value);
            MIMO_Frame_Num = str2double(app.MIMO_Frame_Num_EditField.Value);
            Stereo_Camera_Frame_Rate = str2double(app.Stereo_Camera_Frame_Rate_EditField.Value);

            if (app.MIMO_Tx_Pola_Mode_DropDown.Value == "H极化")
                MIMO_Tx_Pola_Mode = 0;
            elseif (app.MIMO_Tx_Pola_Mode_DropDown.Value == "V极化")
                MIMO_Tx_Pola_Mode = 1;
            elseif (app.MIMO_Tx_Pola_Mode_DropDown.Value == "H/V极化")
                MIMO_Tx_Pola_Mode = 2;
            end
            if (app.MIMO_Rx_Pola_Mode_DropDown.Value == "H极化")
                MIMO_Rx_Pola_Mode = 0;
            elseif (app.MIMO_Rx_Pola_Mode_DropDown.Value == "V极化")
                MIMO_Rx_Pola_Mode = 1;
            elseif (app.MIMO_Rx_Pola_Mode_DropDown.Value == "H/V极化")
                MIMO_Rx_Pola_Mode = 2;
            end
            if (app.MIMO_Interference_Mode_DropDown.Value == "上阵列")
                MIMO_Interference_Mode = 0;
            elseif (app.MIMO_Interference_Mode_DropDown.Value == "下阵列")
                MIMO_Interference_Mode = 1;
            elseif (app.MIMO_Interference_Mode_DropDown.Value == "上/下阵列")
                MIMO_Interference_Mode = 2;
            end
            if (app.MIMO_Control_Mode_DropDown.Value == "模式一")
                MIMO_Control_Mode = 0;
            elseif (app.MIMO_Control_Mode_DropDown.Value == "模式二")
                MIMO_Control_Mode = 1;
            end

            TCP_Send_Data_Head_ID = 0xB2B20821;
            TCP_Send_Data_Command = 0x11111111;

            TCP_Send_Data_0 = bitshift(bitand(uint32(ADC_Sampling_Rate_Div_Multiples), 0x000000FF), 24) ...
                            + bitshift(bitand(uint32(ADC_Sampling_Trigger_Delay), 0x0000FFFF), 8) ...
                            + uint32(bitshift(bitand(typecast(int32(ADC_Sampling_Start_Delay_Fine_Tuning), 'uint32'), 0x0000FF00), -8));
            TCP_Send_Data_1 = uint32(bitshift(bitand(typecast(int32(ADC_Sampling_Start_Delay_Fine_Tuning), 'uint32'), 0x000000FF), 24)) ...
                            + bitshift(bitand(uint32(ADC_Sampling_Start_Byte), 0x0000000F), 20) ...
                            + bitshift(bitand(uint32(ADC_Sampling_Point_Num), 0x000FFFFF), 0);
            TCP_Send_Data_2 = bitshift(bitand(uint32(ADC_Sampling_FIFO_Output_Delay), 0x0000FFFF), 16) ...
                            + bitshift(bitand(uint32(Middle_Data_Check), 0x0000FFFF), 0);
            TCP_Send_Data_3 = bitshift(bitand(uint32(FMCW_Center_Freq), 0x03FFFFFF), 6) ...
                            + bitshift(bitand(uint32(FMCW_Band_Width), 0x001F8000), -15);
            TCP_Send_Data_4 = bitshift(bitand(uint32(FMCW_Band_Width), 0x00007FFF), 17) ...
                            + bitshift(bitand(uint32(FMCW_Slope), 0x03FFFE00), -9);
            TCP_Send_Data_5 = bitshift(bitand(uint32(FMCW_Slope), 0x000001FF), 23) ...
                            + bitshift(bitand(uint32(FMCW_Pulse_Period), 0x00000FFF), 11) ...
                            + bitshift(bitand(uint32(FMCW_Frame_Period), 0x0000FFE0), -5);
            TCP_Send_Data_6 = bitshift(bitand(uint32(FMCW_Frame_Period), 0x0000001F), 27) ...
                            + bitshift(bitand(uint32(FMCW_Output_Power), 0x0000003F), 21) ...
                            + bitshift(bitand(uint32(MIMO_Control_Mode), 0x00000003), 19) ...
                            + bitshift(bitand(uint32(MIMO_Frame_Num), 0x00FFFFE0), -5);
            TCP_Send_Data_7 = bitshift(bitand(uint32(MIMO_Frame_Num), 0x0000001F), 27) ...
                            + bitshift(bitand(uint32(MIMO_Signal_Delay), 0x000001FF), 18) ...
                            + bitshift(bitand(uint32(MIMO_Tx_Pola_Mode), 0x00000003), 16) ...
                            + bitshift(bitand(uint32(MIMO_Rx_Pola_Mode), 0x00000003), 14) ...
                            + bitshift(bitand(uint32(MIMO_Interference_Mode), 0x00000003), 12) ...
                            + bitshift(bitand(uint32(Stereo_Camera_Frame_Rate), 0x0000007F), 5);
            
            TCP_Send_Data = uint32([TCP_Send_Data_Head_ID, TCP_Send_Data_Command, ...
                                    TCP_Send_Data_0, TCP_Send_Data_1, TCP_Send_Data_2, TCP_Send_Data_3, ...
                                    TCP_Send_Data_4, TCP_Send_Data_5, TCP_Send_Data_6, TCP_Send_Data_7]);
            write(app.TCP_Client_Obj_ADC_Sampling_Board_1, TCP_Send_Data, "uint32");
        end

        % Button pushed function: FMCW_Start_Button
        function FMCW_Start_ButtonPushed(app, event)
            TCP_Send_Data_Head_ID = 0xB2B20821;
            TCP_Send_Data_Command = 0x22222222;
            TCP_Send_Data = uint32([TCP_Send_Data_Head_ID, TCP_Send_Data_Command]);
            write(app.TCP_Client_Obj_ADC_Sampling_Board_1, TCP_Send_Data, "uint32");   
        end

        % Button pushed function: FMCW_Stop_Button
        function FMCW_Stop_ButtonPushed(app, event)
            TCP_Send_Data_Head_ID = 0xB2B20821;
            TCP_Send_Data_Command = 0x33333333;
            TCP_Send_Data = uint32([TCP_Send_Data_Head_ID, TCP_Send_Data_Command]);
            write(app.TCP_Client_Obj_ADC_Sampling_Board_1, TCP_Send_Data, "uint32");
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                if isprop(app, 'TCP_Client_Obj_ADC_Sampling_Board_1') && ~isempty(app.TCP_Client_Obj_ADC_Sampling_Board_1)
                    clear(app.TCP_Client_Obj_ADC_Sampling_Board_1);
                    app.TCP_Client_Obj_ADC_Sampling_Board_1 = [];
                    Message = "TCP连接已断开";
                else
                    Message = "无有效TCP连接可断开";
                end
            catch ME
                Message = "断开连接时出错: " + ME.message;
            end
            Original_Text = string(app.Message_Display_TextArea.Value);
            app.Message_Display_TextArea.Value = [Original_Text; Message];
            try
                if isvalid(app.TCP_Timer_ADC_Sampling_Board_1)
                    stop(app.TCP_Timer_ADC_Sampling_Board_1);
                    delete(app.TCP_Timer_ADC_Sampling_Board_1);
                    Message = "TCP计时器已停止";
                else
                    Message = "无有效TCP计时器可停止";
                end
            catch ME
                Message = "停止计时器失败: " + ME.message;
            end
            Original_Text = string(app.Message_Display_TextArea.Value);
            app.Message_Display_TextArea.Value = [Original_Text; Message];

            % TODO
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Parameter_Config_Button = uibutton(app.UIFigure, 'push');
            app.Parameter_Config_Button.ButtonPushedFcn = createCallbackFcn(app, @Parameter_Config_ButtonPushed, true);
            app.Parameter_Config_Button.Text = '参数配置';
            app.TCP_Connect_ADC_Sampling_Board_1_Button = uibutton(app.UIFigure, 'push');
            app.TCP_Connect_ADC_Sampling_Board_1_Button.ButtonPushedFcn = createCallbackFcn(app, @TCP_Connect_ADC_Sampling_Board_1_ButtonPushed, true);
            app.TCP_Connect_ADC_Sampling_Board_1_Button.Text = '连接TCP';
            app.TCP_DisConnect_ADC_Sampling_Board_1_Button = uibutton(app.UIFigure, 'push');
            app.TCP_DisConnect_ADC_Sampling_Board_1_Button.ButtonPushedFcn = createCallbackFcn(app, @TCP_DisConnect_ADC_Sampling_Board_1_ButtonPushed, true);
            app.TCP_DisConnect_ADC_Sampling_Board_1_Button.Text = '断开TCP';
            app.IPEditFieldLabel = uilabel(app.UIFigure);
            app.IPEditFieldLabel.Text = 'IP地址';
            app.TCP_Address_ADC_Sampling_Board_1_EditField = uieditfield(app.UIFigure, 'text');
            app.TCP_Address_ADC_Sampling_Board_1_EditField.Value = '192.168.1.10';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '端口号';
            app.TCP_Port_ADC_Sampling_Board_1_EditField = uieditfield(app.UIFigure, 'text');
            app.TCP_Port_ADC_Sampling_Board_1_EditField.Value = '5001';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '信息监控窗口';
            app.Message_Display_TextArea = uitextarea(app.UIFigure);
            app.ADCPanel = uipanel(app.UIFigure);
            app.ADCPanel.Title = '多通道ADC采集板';
            app.ADCLabel = uilabel(app.ADCPanel);
            app.ADCLabel.Text = 'ADC采样率分频系数';
            app.ADCLabel_2 = uilabel(app.ADCPanel);
            app.ADCLabel_2.Text = 'ADC开始采样触发时延';
            app.ADCLabel_3 = uilabel(app.ADCPanel);
            app.ADCLabel_3.Text = 'ADC开始采样时延微调';
            app.ADCLabel_4 = uilabel(app.ADCPanel);
            app.ADCLabel_4.Text = 'ADC采样开始字节';
            app.ADCLabel_5 = uilabel(app.ADCPanel);
            app.ADCLabel_5.Text = 'ADC采样点数';
            app.ADCLabel_6 = uilabel(app.ADCPanel);
            app.ADCLabel_6.Text = 'FIFO开始输出触发时延';
            app.ADC_Sampling_Rate_Div_Multiples_EditField = uieditfield(app.ADCPanel, 'text');
            app.ADC_Sampling_Rate_Div_Multiples_EditField.ValueChangedFcn = createCallbackFcn(app, @ADC_Sampling_Rate_Div_Multiples_EditFieldValueChanged, true);
            app.ADC_Sampling_Rate_Div_Multiples_EditField.Value = '5';
            app.ADC_Sampling_Trigger_Delay_EditField = uieditfield(app.ADCPanel, 'text');
            app.ADC_Sampling_Trigger_Delay_EditField.ValueChangedFcn = createCallbackFcn(app, @ADC_Sampling_Trigger_Delay_EditFieldValueChanged, true);
            app.ADC_Sampling_Trigger_Delay_EditField.Value = '4000';
            app.ADC_Sampling_Start_Delay_Fine_Tuning_EditField = uieditfield(app.ADCPanel, 'text');
            app.ADC_Sampling_Start_Delay_Fine_Tuning_EditField.Value = '5';
            app.ADC_Sampling_Start_Byte_EditField = uieditfield(app.ADCPanel, 'text');
            app.ADC_Sampling_Start_Byte_EditField.Value = '5';
            app.ADC_Sampling_Point_Num_EditField = uieditfield(app.ADCPanel, 'text');
            app.ADC_Sampling_Point_Num_EditField.ValueChangedFcn = createCallbackFcn(app, @ADC_Sampling_Point_Num_EditFieldValueChanged, true);
            app.ADC_Sampling_Point_Num_EditField.Value = '20000';
            app.ADC_Sampling_FIFO_Output_Delay_EditField = uieditfield(app.ADCPanel, 'text');
            app.ADC_Sampling_FIFO_Output_Delay_EditField.ValueChangedFcn = createCallbackFcn(app, @ADC_Sampling_FIFO_Output_Delay_EditFieldValueChanged, true);
            app.ADC_Sampling_FIFO_Output_Delay_EditField.Value = '3908';
            app.ADC_Sampling_Trigger_Delay_Label = uilabel(app.ADCPanel);
            app.ADC_Sampling_Trigger_Delay_Label.Text = '个400 MHz时钟（10 us）';
            app.MHzLabel_5 = uilabel(app.ADCPanel);
            app.MHzLabel_5.Text = '个400 MHz时钟';
            app.ADC_Sampling_Total_Time_Label = uilabel(app.ADCPanel);
            app.ADC_Sampling_Total_Time_Label.Text = '个（总采样时间：50 us）';
            app.ADC_Sampling_FIFO_Output_Delay_Label = uilabel(app.ADCPanel);
            app.ADC_Sampling_FIFO_Output_Delay_Label.Text = '个390.625 MHz时钟（>10 us）';
            app.ADC_Sampling_Rate_Label = uilabel(app.ADCPanel);
            app.ADC_Sampling_Rate_Label.Text = '（采样率：400 Msps）';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '信号源';
            app.ADCLabel_7 = uilabel(app.Panel);
            app.ADCLabel_7.Text = 'FMCW中心频率';
            app.FMCW_Center_Freq_EditField = uieditfield(app.Panel, 'text');
            app.FMCW_Center_Freq_EditField.Value = '37.5';
            app.ADCLabel_8 = uilabel(app.Panel);
            app.ADCLabel_8.Text = 'FMCW带宽';
            app.ADCLabel_9 = uilabel(app.Panel);
            app.ADCLabel_9.Text = 'FMCW调频斜率';
            app.ADCLabel_10 = uilabel(app.Panel);
            app.ADCLabel_10.Text = 'FMCW脉冲周期';
            app.ADCLabel_11 = uilabel(app.Panel);
            app.ADCLabel_11.Text = 'FMCW帧周期';
            app.ADCLabel_12 = uilabel(app.Panel);
            app.ADCLabel_12.Text = 'FMCW输出功率';
            app.FMCW_Band_Width_EditField = uieditfield(app.Panel, 'text');
            app.FMCW_Band_Width_EditField.ValueChangedFcn = createCallbackFcn(app, @FMCW_Band_Width_EditFieldValueChanged, true);
            app.FMCW_Band_Width_EditField.Value = '1000';
            app.FMCW_Slope_EditField = uieditfield(app.Panel, 'text');
            app.FMCW_Slope_EditField.ValueChangedFcn = createCallbackFcn(app, @FMCW_Slope_EditFieldValueChanged, true);
            app.FMCW_Slope_EditField.Value = '20';
            app.FMCW_Pulse_Period_EditField = uieditfield(app.Panel, 'text');
            app.FMCW_Pulse_Period_EditField.ValueChangedFcn = createCallbackFcn(app, @FMCW_Pulse_Period_EditFieldValueChanged, true);
            app.FMCW_Pulse_Period_EditField.Value = '60';
            app.FMCW_Frame_Period_EditField = uieditfield(app.Panel, 'text');
            app.FMCW_Frame_Period_EditField.ValueChangedFcn = createCallbackFcn(app, @FMCW_Frame_Period_EditFieldValueChanged, true);
            app.FMCW_Frame_Period_EditField.Value = '1000';
            app.FMCW_Output_Power_EditField = uieditfield(app.Panel, 'text');
            app.FMCW_Output_Power_EditField.Value = '10';
            app.GHzLabel = uilabel(app.Panel);
            app.GHzLabel.Text = 'GHz';
            app.MHzLabel_4 = uilabel(app.Panel);
            app.MHzLabel_4.Text = 'MHz';
            app.MHzusLabel = uilabel(app.Panel);
            app.MHzusLabel.Text = 'MHz/us';
            app.usLabel = uilabel(app.Panel);
            app.usLabel.Text = 'us';
            app.usLabel_2 = uilabel(app.Panel);
            app.usLabel_2.Text = 'us';
            app.dBmLabel = uilabel(app.Panel);
            app.dBmLabel.Text = 'dBm';
            app.ADCLabel_17 = uilabel(app.Panel);
            app.ADCLabel_17.Text = 'FMCW脉冲宽度';
            app.FMCW_Pulse_Width_EditField = uieditfield(app.Panel, 'text');
            app.FMCW_Pulse_Width_EditField.Editable = 'off';
            app.FMCW_Pulse_Width_EditField.Value = '50';
            app.usLabel_3 = uilabel(app.Panel);
            app.usLabel_3.Text = 'us';
            app.ADCLabel_15 = uilabel(app.Panel);
            app.ADCLabel_15.Text = '本振信号时延';
            app.MIMO_Signal_Delay_EditField = uieditfield(app.Panel, 'text');
            app.MIMO_Signal_Delay_EditField.Value = '0';
            app.nsLabel = uilabel(app.Panel);
            app.nsLabel.Text = 'ns';
            app.ADCLabel_14 = uilabel(app.Panel);
            app.ADCLabel_14.Text = 'MIMO成像帧数';
            app.MIMO_Frame_Num_EditField = uieditfield(app.Panel, 'text');
            app.MIMO_Frame_Num_EditField.ValueChangedFcn = createCallbackFcn(app, @MIMO_Frame_Num_EditFieldValueChanged, true);
            app.MIMO_Frame_Num_EditField.Value = '1000000';
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = '帧';
            app.ADCLabel_18 = uilabel(app.Panel);
            app.ADCLabel_18.Text = 'MIMO工作时长';
            app.MIMO_Work_Time_EditField = uieditfield(app.Panel, 'text');
            app.MIMO_Work_Time_EditField.Editable = 'off';
            app.MIMO_Work_Time_EditField.Value = '100';
            app.sLabel = uilabel(app.Panel);
            app.sLabel.Text = 's';
            app.DropDownLabel = uilabel(app.Panel);
            app.DropDownLabel.Text = '发射极化模式';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '接收极化模式';
            app.MIMO_Tx_Pola_Mode_DropDown = uidropdown(app.Panel);
            app.MIMO_Tx_Pola_Mode_DropDown.Items = {'H极化', 'V极化', 'H/V极化'};
            app.MIMO_Tx_Pola_Mode_DropDown.ValueChangedFcn = createCallbackFcn(app, @MIMO_Tx_Pola_Mode_DropDownValueChanged, true);
            app.MIMO_Tx_Pola_Mode_DropDown.Value = 'H极化';
            app.MIMO_Rx_Pola_Mode_DropDown = uidropdown(app.Panel);
            app.MIMO_Rx_Pola_Mode_DropDown.Items = {'H极化', 'V极化', 'H/V极化'};
            app.MIMO_Rx_Pola_Mode_DropDown.ValueChangedFcn = createCallbackFcn(app, @MIMO_Rx_Pola_Mode_DropDownValueChanged, true);
            app.MIMO_Rx_Pola_Mode_DropDown.Value = 'H极化';
            app.MIMODropDown_2Label = uilabel(app.Panel);
            app.MIMODropDown_2Label.Text = 'MIMO干涉模式';
            app.MIMO_Interference_Mode_DropDown = uidropdown(app.Panel);
            app.MIMO_Interference_Mode_DropDown.Items = {'上阵列', '下阵列', '上/下阵列'};
            app.MIMO_Interference_Mode_DropDown.ValueChangedFcn = createCallbackFcn(app, @MIMO_Interference_Mode_DropDownValueChanged, true);
            app.MIMO_Interference_Mode_DropDown.Value = '上阵列';
            app.MIMODropDownLabel = uilabel(app.Panel);
            app.MIMODropDownLabel.Text = 'MIMO控制模式';
            app.MIMO_Control_Mode_DropDown = uidropdown(app.Panel);
            app.MIMO_Control_Mode_DropDown.Items = {'模式一', '模式二'};
            app.MIMO_Control_Mode_DropDown.Value = '模式一';
            app.ADCLabel_16 = uilabel(app.Panel);
            app.ADCLabel_16.Text = '双目相机帧率';
            app.Stereo_Camera_Frame_Rate_EditField = uieditfield(app.Panel, 'text');
            app.Stereo_Camera_Frame_Rate_EditField.Value = '100';
            app.fpsLabel = uilabel(app.Panel);
            app.fpsLabel.Text = 'fps';
            app.FMCW_Start_Button = uibutton(app.UIFigure, 'push');
            app.FMCW_Start_Button.ButtonPushedFcn = createCallbackFcn(app, @FMCW_Start_ButtonPushed, true);
            app.FMCW_Start_Button.Text = '开始发射';
            app.FMCW_Stop_Button = uibutton(app.UIFigure, 'push');
            app.FMCW_Stop_Button.ButtonPushedFcn = createCallbackFcn(app, @FMCW_Stop_ButtonPushed, true);
            app.FMCW_Stop_Button.Text = '停止发射';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '高速存储板';
            app.TCP_Connect_Button_2 = uibutton(app.Panel_2, 'push');
            app.TCP_Connect_Button_2.Text = '连接TCP';
            app.TCP_DisConnect_Button_5 = uibutton(app.Panel_2, 'push');
            app.TCP_DisConnect_Button_5.Text = '断开TCP';
            app.IPEditFieldLabel_2 = uilabel(app.Panel_2);
            app.IPEditFieldLabel_2.Text = 'IP地址';
            app.TCP_Address_EditField_20 = uieditfield(app.Panel_2, 'text');
            app.TCP_Address_EditField_20.Value = '192.168.2.10';
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Text = '端口号';
            app.TCP_Port_EditField_2 = uieditfield(app.Panel_2, 'text');
            app.TCP_Port_EditField_2.Value = '5001';
            app.TCP_DisConnect_Button_6 = uibutton(app.Panel_2, 'push');
            app.TCP_DisConnect_Button_6.Text = '查询';
            app.TCP_DisConnect_Button_7 = uibutton(app.Panel_2, 'push');
            app.TCP_DisConnect_Button_7.Text = '下载';
            app.TCP_DisConnect_Button_8 = uibutton(app.Panel_2, 'push');
            app.TCP_DisConnect_Button_8.Text = '删除';
            app.UITable = uitable(app.Panel_2);
            app.UITable.ColumnName = {'序号'; '文件大小(Bytes)'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.SimulationProgress = uisimprogress(app.Panel_2);
            app.SimulationProgress.ShowSimTime = 'off';
            app.SimulationProgress.ShowElapsedTime = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PC_UP_Control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end